classdef Odev1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SelectImageButton   matlab.ui.control.Button
        RGBToGrayButton     matlab.ui.control.Button
        RedSliderLabel      matlab.ui.control.Label
        RedSlider           matlab.ui.control.Slider
        GreenSliderLabel    matlab.ui.control.Label
        GreenSlider         matlab.ui.control.Slider
        BlueSliderLabel     matlab.ui.control.Label
        BlueSlider          matlab.ui.control.Slider
        ThresholdButton     matlab.ui.control.Button
        ImageDropDownLabel  matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        EditField           matlab.ui.control.EditField
        EditField2          matlab.ui.control.EditField
        EditField3          matlab.ui.control.EditField
        RGBtoRGBButton      matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes3             matlab.ui.control.UIAxes
        UIAxes4             matlab.ui.control.UIAxes
        UIAxes5             matlab.ui.control.UIAxes
        UIAxes6             matlab.ui.control.UIAxes
        UIAxes7             matlab.ui.control.UIAxes
        UIAxes8             matlab.ui.control.UIAxes
        UIAxes9             matlab.ui.control.UIAxes
        UIAxes10            matlab.ui.control.UIAxes
        UIAxes11            matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        
        function updateimage(app,imagefile)
            global im;        
             if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
             
                   
          switch size(im,3)
              case 1    
                 
                   % Display the grayscale image
                  imagesc(app.UIAxes,im);
                  histr=histogram(app.UIAxes2,im,'FaceColor',[1,0,0],"EdgeColor","none");
                  histg=histogram(app.UIAxes3,im,'FaceColor',[0,1,0],"EdgeColor","none");
                  histb=histogram(app.UIAxes4,im,'FaceColor',[0,0,1],"EdgeColor","none");
                  
              case 3
                
                  %Display the truecolor image
                  imagesc(app.UIAxes,im);
                  %Plot the histogram
                  histr=histogram(app.UIAxes2,im(:,:,1),'FaceColor',[1,0,0],"EdgeColor","none");
                  histg=histogram(app.UIAxes3,im(:,:,2),'FaceColor',[0,1,0],"EdgeColor","none");
                  histb=histogram(app.UIAxes4,im(:,:,3),'FaceColor',[0,0,1],"EdgeColor","none");
                
              otherwise
                  uialert(app.UIFigure,"image must be grayscale or truecolor.","image error");
                  return;         
          end
                % Get largest bin count
                maxr = max(histr.BinCounts);
                maxg= max(histg.BinCounts);
                maxb= max(histb.BinCounts);             
                maxcount = max([maxr maxg maxb ]);
                
                 % Set y axes limits based on largest bin count
                app.UIAxes2.YLim = [0 maxcount];
                app.UIAxes2.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.UIAxes3.YLim= [0 maxcount];
                app.UIAxes3.YTick = round([0 maxcount/2 maxcount], 2, "significant" );
                app.UIAxes4.YLim= [0 maxcount];
                app.UIAxes4.YTick = round([0 maxcount/2 maxcount], 2, "significant" );           
        end
       
        
   end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              % Configure image axes
            app.UIAxes.Visible = 'off';
            app.UIAxes.Colormap = gray(256);
            axis(app.UIAxes, 'image');
  
            % Update the image 
            updateimage(app,'peppers.png');
            
             
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed2(app, event)
               
            %a= imread('C:\Users\besar\Desktop\kariyer\resim.jpg');
            %imshow(a,'parent',app.UIAxes);
            
             % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);  
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
       
               updateimage(app, fname);
            end
        end

        % Button pushed function: RGBToGrayButton
        function RGBToGrayButtonPushed2(app, event)
 
            global im;
            global b;

            try
                      b= rgb2gray(im);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
            end 
            imshow(b,'parent',app.UIAxes5);
          
          
        end

        % Button pushed function: ThresholdButton
        function ThresholdButtonPushed(app, event)
            global d;
            global e;
            global im;
               A=app.RedSlider.Value;
               B=app.GreenSlider.Value;
               C=app.BlueSlider.Value;
               [r1,r2,r3]=size(im);
                if r3==3
                  out1=im(:,:,1)>A;
                  out2=im(:,:,2)>B;
                  out3=im(:,:,3)>C;               
               imshow(out1,'Parent',app.UIAxes8);
               imshow(out2,'Parent',app.UIAxes6);
               imshow(out3,'Parent',app.UIAxes7);
                elseif r3==1
                   out=im>A; 
                      imshow(out,'Parent',app.UIAxes8);
                end
                  %for i=1:r1
                   % for j=1:r2
                       %if im(:,:,1)>A
                      %          out1(i,j)=255;
                     %        else
                    %            out1(i,j)=0;
                   %           
                  %      end
                 %        if im(:,:,2)>B
                         %       out2(i,j)=255;
                        %     else
                       %         out2(i,j)=0;  
                      %   end
                     %    if im(:,:,3)>C
                    %            out3(i,j)=255;
                   %          else
                  %              out3(i,j)=0;
                 %       end
                %    end
                % end


        end

        % Value changed function: DropDown
        function ImageDropDownValueChanged(app, event)

          updateimage(app, app.DropDown.Value);
            
           
        end

        % Value changed function: RedSlider
        function RedSliderValueChanged(app, event)
            value = app.RedSlider.Value; 
            value=round(value); % round to integer
            value1=num2str(value);
            app.EditField.Value=value1;
        end

        % Value changed function: GreenSlider
        function GreenSliderValueChanged(app, event)
            value = app.GreenSlider.Value;
            value=round(value); % round to integer
            value1=num2str(value);
            app.EditField2.Value=value1;
        end

        % Value changed function: BlueSlider
        function BlueSliderValueChanged(app, event)
            value = app.BlueSlider.Value; 
            value=round(value); % round to integer
            value1=num2str(value);
            app.EditField3.Value=value1;
        end

        % Button pushed function: RGBtoRGBButton
        function RGBtoRGBButtonPushed(app, event)
           global im;
           try
            x=im(:,:,1);
            y=im(:,:,2);
            z=im(:,:,3);
            imshow(x,'Parent',app.UIAxes9);
            imshow(y,'Parent',app.UIAxes10);
            imshow(z,'Parent',app.UIAxes11); 
           catch ME
             uialert(app.UIFigure,ME.message,'image error');  
           end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed2, true);
            app.SelectImageButton.Text = 'Select Image';
            app.RGBToGrayButton = uibutton(app.UIFigure, 'push');
            app.RGBToGrayButton.ButtonPushedFcn = createCallbackFcn(app, @RGBToGrayButtonPushed2, true);
            app.RGBToGrayButton.Text = 'RGB To Gray';
            app.RedSliderLabel = uilabel(app.UIFigure);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.UIFigure);
            app.RedSlider.Limits = [0 255];
            app.RedSlider.ValueChangedFcn = createCallbackFcn(app, @RedSliderValueChanged, true);
            app.GreenSliderLabel = uilabel(app.UIFigure);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.UIFigure);
            app.GreenSlider.Limits = [0 255];
            app.GreenSlider.ValueChangedFcn = createCallbackFcn(app, @GreenSliderValueChanged, true);
            app.BlueSliderLabel = uilabel(app.UIFigure);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.UIFigure);
            app.BlueSlider.Limits = [0 255];
            app.BlueSlider.ValueChangedFcn = createCallbackFcn(app, @BlueSliderValueChanged, true);
            app.ThresholdButton = uibutton(app.UIFigure, 'push');
            app.ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdButtonPushed, true);
            app.ThresholdButton.Text = 'Threshold';
            app.ImageDropDownLabel = uilabel(app.UIFigure);
            app.ImageDropDownLabel.Text = 'Image';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Peppers', 'Corn', 'Cameraman'};
            app.DropDown.ItemsData = {'peppers.png', 'corn.tif', 'cameraman.tif'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @ImageDropDownValueChanged, true);
            app.DropDown.Value = 'peppers.png';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.EditField3 = uieditfield(app.UIFigure, 'text');
            app.RGBtoRGBButton = uibutton(app.UIFigure, 'push');
            app.RGBtoRGBButton.ButtonPushedFcn = createCallbackFcn(app, @RGBtoRGBButtonPushed, true);
            app.RGBtoRGBButton.Text = 'RGB  to R-G-B';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Red')
            xlabel(app.UIAxes2, 'Intensity')
            ylabel(app.UIAxes2, 'Pixels')
            app.UIAxes2.XTick = [0 128 255];
            app.UIAxes2.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Green')
            xlabel(app.UIAxes3, 'Intensity')
            ylabel(app.UIAxes3, 'Pixels')
            app.UIAxes3.XTick = [0 128 255];
            app.UIAxes3.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Blue')
            xlabel(app.UIAxes4, 'Intensity')
            ylabel(app.UIAxes4, 'Pixels')
            app.UIAxes4.XTick = [0 128 255];
            app.UIAxes4.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Gray Scale')
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Green')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'Blue')
            zlabel(app.UIAxes7, {''; ''})
            app.UIAxes8 = uiaxes(app.UIFigure);
            title(app.UIAxes8, 'Red or Gray')
            app.UIAxes9 = uiaxes(app.UIFigure);
            title(app.UIAxes9, 'Red')
            xlabel(app.UIAxes9, {''; ''})
            app.UIAxes9.XTick = [];
            app.UIAxes9.YTick = [];
            app.UIAxes10 = uiaxes(app.UIFigure);
            title(app.UIAxes10, 'Green')
            app.UIAxes10.XTick = [];
            app.UIAxes10.YTick = [];
            app.UIAxes11 = uiaxes(app.UIFigure);
            title(app.UIAxes11, 'Blue')
            app.UIAxes11.XTick = [];
            app.UIAxes11.XTickLabel = '';
            app.UIAxes11.YTick = [];
            app.UIAxes11.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Odev1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end