classdef Odev2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        Tab                        matlab.ui.container.Tab
        SelectImageButton          matlab.ui.control.Button
        RGBToGrayButton            matlab.ui.control.Button
        RedSlider                  matlab.ui.control.Slider
        GreenSlider                matlab.ui.control.Slider
        BlueSlider                 matlab.ui.control.Slider
        ThresholdButton            matlab.ui.control.Button
        DropDown                   matlab.ui.control.DropDown
        EditField                  matlab.ui.control.EditField
        EditField2                 matlab.ui.control.EditField
        EditField3                 matlab.ui.control.EditField
        RGBtoRGBButton             matlab.ui.control.Button
        ImageDropDownLabel         matlab.ui.control.Label
        RedSliderLabel             matlab.ui.control.Label
        GreenSliderLabel           matlab.ui.control.Label
        BlueSliderLabel            matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes4                    matlab.ui.control.UIAxes
        UIAxes5                    matlab.ui.control.UIAxes
        UIAxes6                    matlab.ui.control.UIAxes
        UIAxes7                    matlab.ui.control.UIAxes
        UIAxes8                    matlab.ui.control.UIAxes
        UIAxes9                    matlab.ui.control.UIAxes
        UIAxes10                   matlab.ui.control.UIAxes
        UIAxes11                   matlab.ui.control.UIAxes
        Tab2                       matlab.ui.container.Tab
        cLabel                     matlab.ui.control.Label
        cEditField                 matlab.ui.control.NumericEditField
        LOGTRANSFORMATONSButton    matlab.ui.control.Button
        MEDIANFLTERSButton         matlab.ui.control.Button
        mEditFieldLabel            matlab.ui.control.Label
        mEditField                 matlab.ui.control.NumericEditField
        D0EditFieldLabel           matlab.ui.control.Label
        D0EditField                matlab.ui.control.NumericEditField
        GAUSSIANFLTERSButton       matlab.ui.control.Button
        GAMMATRANSFORMATONSButton  matlab.ui.control.Button
        yEditFieldLabel            matlab.ui.control.Label
        yEditField                 matlab.ui.control.NumericEditField
        cEditField_2Label          matlab.ui.control.Label
        cEditField_2               matlab.ui.control.NumericEditField
        UIAxes12                   matlab.ui.control.UIAxes
        UIAxes13                   matlab.ui.control.UIAxes
        UIAxes16                   matlab.ui.control.UIAxes
        UIAxes17                   matlab.ui.control.UIAxes
        UIAxes18                   matlab.ui.control.UIAxes
        UIAxes14                   matlab.ui.control.UIAxes
        UIAxes15                   matlab.ui.control.UIAxes
        Tab3                       matlab.ui.container.Tab
        AYRIKFOURERDNMButton       matlab.ui.control.Button
        UIAxes19                   matlab.ui.control.UIAxes
        UIAxes20                   matlab.ui.control.UIAxes
        UIAxes21                   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        
        function updateimage(app,imagefile)
            global im;        
             if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
             
                   
          switch size(im,3)
              case 1    
                 
                   % Display the grayscale image
                  imagesc(app.UIAxes,im);
                  histr=histogram(app.UIAxes2,im,'FaceColor',[1,0,0],"EdgeColor","none");
                  histg=histogram(app.UIAxes3,im,'FaceColor',[0,1,0],"EdgeColor","none");
                  histb=histogram(app.UIAxes4,im,'FaceColor',[0,0,1],"EdgeColor","none");
                  
              case 3
                
                  %Display the truecolor image
                  imagesc(app.UIAxes,im); 
                  %Plot the histogram
                  histr=histogram(app.UIAxes2,im(:,:,1),'FaceColor',[1,0,0],"EdgeColor","none");
                  histg=histogram(app.UIAxes3,im(:,:,2),'FaceColor',[0,1,0],"EdgeColor","none");
                  histb=histogram(app.UIAxes4,im(:,:,3),'FaceColor',[0,0,1],"EdgeColor","none");
                
              otherwise
                  uialert(app.UIFigure,"image must be grayscale or truecolor.","image error");
                  return;         
          end
                % Get largest bin count
                maxr = max(histr.BinCounts);
                maxg= max(histg.BinCounts);
                maxb= max(histb.BinCounts);             
                maxcount = max([maxr maxg maxb ]);
                
                 % Set y axes limits based on largest bin count
                app.UIAxes2.YLim = [0 maxcount];
                app.UIAxes2.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.UIAxes3.YLim= [0 maxcount];
                app.UIAxes3.YTick = round([0 maxcount/2 maxcount], 2, "significant" );
                app.UIAxes4.YLim= [0 maxcount];
                app.UIAxes4.YTick = round([0 maxcount/2 maxcount], 2, "significant" );           
        end
       
        
   end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              % Configure image axes
            app.UIAxes.Visible = 'off';
            app.UIAxes.Colormap = gray(256);
            axis(app.UIAxes, 'image');
  
            % Update the image 
            updateimage(app,'peppers.png');
            
             
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed2(app, event)
               
            %a= imread('C:\Users\besar\Desktop\kariyer\resim.jpg');
            %imshow(a,'parent',app.UIAxes);
            
             % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);  
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
       
               updateimage(app, fname);
            end
        end

        % Button pushed function: RGBToGrayButton
        function RGBToGrayButtonPushed2(app, event)
 
            global im;
            global b;

            try
                      b= rgb2gray(im);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
            end 
            imshow(b,'parent',app.UIAxes5);
          
          
        end

        % Button pushed function: ThresholdButton
        function ThresholdButtonPushed(app, event)
          
            global im;
               A=app.RedSlider.Value;
               B=app.GreenSlider.Value;
               C=app.BlueSlider.Value;
               [r1,r2,r3]=size(im);
                if r3==3
                  out1=im(:,:,1)>A;
                  out2=im(:,:,2)>B;
                  out3=im(:,:,3)>C;               
               imshow(out1,'Parent',app.UIAxes8);
               imshow(out2,'Parent',app.UIAxes6);
               imshow(out3,'Parent',app.UIAxes7);
                elseif r3==1
                   out=im>A; 
                      imshow(out,'Parent',app.UIAxes8);
                end
                  %for i=1:r1
                   % for j=1:r2
                       %if im(:,:,1)>A
                      %          out1(i,j)=255;
                     %        else
                    %            out1(i,j)=0;
                   %           
                  %      end
                 %        if im(:,:,2)>B
                         %       out2(i,j)=255;
                        %     else
                       %         out2(i,j)=0;  
                      %   end
                     %    if im(:,:,3)>C
                    %            out3(i,j)=255;
                   %          else
                  %              out3(i,j)=0;
                 %       end
                %    end
                % end


        end

        % Value changed function: DropDown
        function ImageDropDownValueChanged(app, event)

          updateimage(app, app.DropDown.Value);
            
           
        end

        % Value changed function: RedSlider
        function RedSliderValueChanged(app, event)
            value = app.RedSlider.Value; 
            value=round(value); % round to integer
            value1=num2str(value);
            app.EditField.Value=value1;
        end

        % Value changed function: GreenSlider
        function GreenSliderValueChanged(app, event)
            value = app.GreenSlider.Value;
            value=round(value); % round to integer
            value1=num2str(value);
            app.EditField2.Value=value1;
        end

        % Value changed function: BlueSlider
        function BlueSliderValueChanged(app, event)
            value = app.BlueSlider.Value; 
            value=round(value); % round to integer
            value1=num2str(value);
            app.EditField3.Value=value1;
        end

        % Button pushed function: RGBtoRGBButton
        function RGBtoRGBButtonPushed(app, event)
           global im;
           try
            x=im(:,:,1);
            y=im(:,:,2);
            z=im(:,:,3);
            imshow(x,'Parent',app.UIAxes9);
            imshow(y,'Parent',app.UIAxes10);
            imshow(z,'Parent',app.UIAxes11); 
           catch ME
             uialert(app.UIFigure,ME.message,'image error');  
           end
           
        end

        % Button pushed function: LOGTRANSFORMATONSButton
        function LOGTRANSFORMATONSButtonPushed(app, event)
            global im;
            c=app.cEditField.Value;
            x=im2double(im);
            s=c*log(1+(x));
            imshow(s,'Parent',app.UIAxes13);
            histogram(app.UIAxes12,s);
            
        end

        % Button pushed function: GAMMATRANSFORMATONSButton
        function GAMMATRANSFORMATONSButtonPushed(app, event)
            global im;
            c=app.cEditField_2.Value;
            y=app.yEditField.Value;
            if c==0 || y==0
                  uialert(app.UIFigure,'c ve y değeri gir','Error');
            end
            x=im2double(im);
            s=c*(x.^y);
            imshow(s,'Parent',app.UIAxes14);
            histogram(app.UIAxes15,s);
        end

        % Button pushed function: MEDIANFLTERSButton
        function MEDIANFLTERSButtonPushed(app, event)
           global im;
           [r1,r2,r3]=size(im);
           if r3==3
                 a=rgb2gray(im);
           else
               a=im;
           end 
           m=app.mEditField.Value;
           if m==0
                 uialert(app.UIFigure,'m değeri gir','Error');
           end
           j=imnoise(a,'salt & pepper',0.02);
           i=medfilt2(j,[m m]);
           imshow(i,'Parent',app.UIAxes16);
           imshow(j,'Parent',app.UIAxes17);
           
        end

        % Button pushed function: GAUSSIANFLTERSButton
        function GAUSSIANFLTERSButtonPushed(app, event)
            global im;
            d0= app.D0EditField.Value;    
            if d0==0
                uialert(app.UIFigure,'d0 değeri gir','Error');
            end
            %a=imgaussfilt(im,d0);
            I=rgb2gray(im); % convert the image to grey 

            A = fft2(double(I)); % compute FFT of the grey image
            A1=fftshift(A); % frequency scaling


            % Gaussian Filter Response Calculation

            [M N]=size(A); % image size
            R=10; % filter size parameter 
            X=0:N-1;
            Y=0:M-1;
            [X Y]=meshgrid(X,Y);
            Cx=0.5*N;
            Cy=0.5*M;
            Lo=exp(-((X-Cx).^2+(Y-Cy).^2)./(2*d0).^2);
    
            % Filtered image=ifft(filter response*fft(original image))

            J=A1.*Lo;
            J1=ifftshift(J);
            B1=ifft2(J1);

           imshow(abs(B1),[15,290],'Parent',app.UIAxes18);
 
           
        end

        % Button pushed function: AYRIKFOURERDNMButton
        function AYRIKFOURERDNMButtonPushed(app, event)
            global im;
               [r1,r2,r3]=size(im);
           if r3==3
                 a=rgb2gray(im);
           else
               a=im;
           end 
            fftor= fft2(double(a));
            sfft= fftshift(fftor);
            
            g=fft2(a);
            ga=abs(g);
            gp=atan2(imag(g),real(g));
            gb=exp(j*gp);
            gm=real(ifft2(ga));
            gn=real(ifft2(gb));
            gms=im2uint8(mat2gray(gm));
            gns=im2uint8(mat2gray(gn));
            x=abs(sfft);
            x= (x- min(min(x)))./ (max(max(x))).*255;
            imshow(x,'Parent',app.UIAxes19);
            imshow(gns,'Parent',app.UIAxes20);
            imshow(im,'parent',app.UIAxes21);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.SelectImageButton = uibutton(app.Tab, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed2, true);
            app.SelectImageButton.Text = 'Select Image';
            app.RGBToGrayButton = uibutton(app.Tab, 'push');
            app.RGBToGrayButton.ButtonPushedFcn = createCallbackFcn(app, @RGBToGrayButtonPushed2, true);
            app.RGBToGrayButton.Text = 'RGB To Gray';
            app.RedSlider = uislider(app.Tab);
            app.RedSlider.Limits = [0 255];
            app.RedSlider.ValueChangedFcn = createCallbackFcn(app, @RedSliderValueChanged, true);
            app.GreenSlider = uislider(app.Tab);
            app.GreenSlider.Limits = [0 255];
            app.GreenSlider.ValueChangedFcn = createCallbackFcn(app, @GreenSliderValueChanged, true);
            app.BlueSlider = uislider(app.Tab);
            app.BlueSlider.Limits = [0 255];
            app.BlueSlider.ValueChangedFcn = createCallbackFcn(app, @BlueSliderValueChanged, true);
            app.ThresholdButton = uibutton(app.Tab, 'push');
            app.ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdButtonPushed, true);
            app.ThresholdButton.Text = 'Threshold';
            app.DropDown = uidropdown(app.Tab);
            app.DropDown.Items = {'Peppers', 'Corn', 'Cameraman'};
            app.DropDown.ItemsData = {'peppers.png', 'corn.tif', 'cameraman.tif'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @ImageDropDownValueChanged, true);
            app.DropDown.Value = 'peppers.png';
            app.EditField = uieditfield(app.Tab, 'text');
            app.EditField2 = uieditfield(app.Tab, 'text');
            app.EditField3 = uieditfield(app.Tab, 'text');
            app.RGBtoRGBButton = uibutton(app.Tab, 'push');
            app.RGBtoRGBButton.ButtonPushedFcn = createCallbackFcn(app, @RGBtoRGBButtonPushed, true);
            app.RGBtoRGBButton.Text = 'RGB  to R-G-B';
            app.ImageDropDownLabel = uilabel(app.Tab);
            app.ImageDropDownLabel.Text = 'Image';
            app.RedSliderLabel = uilabel(app.Tab);
            app.RedSliderLabel.Text = 'Red';
            app.GreenSliderLabel = uilabel(app.Tab);
            app.GreenSliderLabel.Text = 'Green';
            app.BlueSliderLabel = uilabel(app.Tab);
            app.BlueSliderLabel.Text = 'Blue';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, 'Red')
            xlabel(app.UIAxes2, 'Intensity')
            ylabel(app.UIAxes2, 'Pixels')
            app.UIAxes2.XTick = [0 128 255];
            app.UIAxes2.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes3 = uiaxes(app.Tab);
            title(app.UIAxes3, 'Green')
            xlabel(app.UIAxes3, 'Intensity')
            ylabel(app.UIAxes3, 'Pixels')
            app.UIAxes3.XTick = [0 128 255];
            app.UIAxes3.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes4 = uiaxes(app.Tab);
            title(app.UIAxes4, 'Blue')
            xlabel(app.UIAxes4, 'Intensity')
            ylabel(app.UIAxes4, 'Pixels')
            app.UIAxes4.XTick = [0 128 255];
            app.UIAxes4.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes5 = uiaxes(app.Tab);
            title(app.UIAxes5, 'Gray Scale')
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            app.UIAxes6 = uiaxes(app.Tab);
            title(app.UIAxes6, 'Green')
            app.UIAxes7 = uiaxes(app.Tab);
            title(app.UIAxes7, 'Blue')
            zlabel(app.UIAxes7, {''; ''})
            app.UIAxes8 = uiaxes(app.Tab);
            title(app.UIAxes8, 'Red or Gray')
            app.UIAxes9 = uiaxes(app.Tab);
            title(app.UIAxes9, 'Red')
            xlabel(app.UIAxes9, {''; ''})
            app.UIAxes9.XTick = [];
            app.UIAxes9.YTick = [];
            app.UIAxes10 = uiaxes(app.Tab);
            title(app.UIAxes10, 'Green')
            app.UIAxes10.XTick = [];
            app.UIAxes10.YTick = [];
            app.UIAxes11 = uiaxes(app.Tab);
            title(app.UIAxes11, 'Blue')
            app.UIAxes11.XTick = [];
            app.UIAxes11.XTickLabel = '';
            app.UIAxes11.YTick = [];
            app.UIAxes11.YTickLabel = '';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.cLabel = uilabel(app.Tab2);
            app.cLabel.Text = 'c';
            app.cEditField = uieditfield(app.Tab2, 'numeric');
            app.LOGTRANSFORMATONSButton = uibutton(app.Tab2, 'push');
            app.LOGTRANSFORMATONSButton.ButtonPushedFcn = createCallbackFcn(app, @LOGTRANSFORMATONSButtonPushed, true);
            app.LOGTRANSFORMATONSButton.Text = 'LOG TRANSFORMATİONS';
            app.MEDIANFLTERSButton = uibutton(app.Tab2, 'push');
            app.MEDIANFLTERSButton.ButtonPushedFcn = createCallbackFcn(app, @MEDIANFLTERSButtonPushed, true);
            app.MEDIANFLTERSButton.Text = 'MEDIAN FİLTERS';
            app.mEditFieldLabel = uilabel(app.Tab2);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.Tab2, 'numeric');
            app.D0EditFieldLabel = uilabel(app.Tab2);
            app.D0EditFieldLabel.Text = 'D0';
            app.D0EditField = uieditfield(app.Tab2, 'numeric');
            app.GAUSSIANFLTERSButton = uibutton(app.Tab2, 'push');
            app.GAUSSIANFLTERSButton.ButtonPushedFcn = createCallbackFcn(app, @GAUSSIANFLTERSButtonPushed, true);
            app.GAUSSIANFLTERSButton.Text = 'GAUSSIAN FİLTERS';
            app.GAMMATRANSFORMATONSButton = uibutton(app.Tab2, 'push');
            app.GAMMATRANSFORMATONSButton.ButtonPushedFcn = createCallbackFcn(app, @GAMMATRANSFORMATONSButtonPushed, true);
            app.GAMMATRANSFORMATONSButton.Text = 'GAMMA TRANSFORMATİONS';
            app.yEditFieldLabel = uilabel(app.Tab2);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.Tab2, 'numeric');
            app.cEditField_2Label = uilabel(app.Tab2);
            app.cEditField_2Label.Text = 'c';
            app.cEditField_2 = uieditfield(app.Tab2, 'numeric');
            app.UIAxes12 = uiaxes(app.Tab2);
            title(app.UIAxes12, 'Log Transfrom Histogram')
            xlabel(app.UIAxes12, 'Intesity')
            ylabel(app.UIAxes12, 'Pixel')
            app.UIAxes12.XTick = [0 128 255];
            app.UIAxes12.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes13 = uiaxes(app.Tab2);
            title(app.UIAxes13, 'Log Transform')
            app.UIAxes13.XTick = [];
            app.UIAxes13.YTick = [];
            app.UIAxes16 = uiaxes(app.Tab2);
            title(app.UIAxes16, 'Median Filter')
            app.UIAxes16.XTick = [];
            app.UIAxes16.YTick = [];
            app.UIAxes17 = uiaxes(app.Tab2);
            title(app.UIAxes17, 'Noise Image')
            app.UIAxes17.XTick = [];
            app.UIAxes17.YTick = [];
            app.UIAxes18 = uiaxes(app.Tab2);
            title(app.UIAxes18, 'Gaussian filter')
            app.UIAxes18.XTick = [];
            app.UIAxes18.YTick = [];
            app.UIAxes14 = uiaxes(app.Tab2);
            title(app.UIAxes14, 'Gamma Transfrom')
            app.UIAxes14.XTick = [];
            app.UIAxes14.YTick = [];
            app.UIAxes15 = uiaxes(app.Tab2);
            title(app.UIAxes15, 'GammaTransfrom Histogram')
            xlabel(app.UIAxes15, 'Intesity')
            ylabel(app.UIAxes15, 'Pixel')
            app.UIAxes15.XTick = [0 128 255];
            app.UIAxes15.XTickLabel = {'0'; '128'; '255'};
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.AYRIKFOURERDNMButton = uibutton(app.Tab3, 'push');
            app.AYRIKFOURERDNMButton.ButtonPushedFcn = createCallbackFcn(app, @AYRIKFOURERDNMButtonPushed, true);
            app.AYRIKFOURERDNMButton.Text = 'AYRIK FOURİER DÖNÜŞÜMÜ';
            app.UIAxes19 = uiaxes(app.Tab3);
            title(app.UIAxes19, 'Genlik')
            app.UIAxes19.XTick = [];
            app.UIAxes19.YTick = [];
            app.UIAxes20 = uiaxes(app.Tab3);
            title(app.UIAxes20, 'Faz')
            app.UIAxes20.XTick = [];
            app.UIAxes20.YTick = [];
            app.UIAxes21 = uiaxes(app.Tab3);
            title(app.UIAxes21, 'Original Image')
            app.UIAxes21.XTick = [];
            app.UIAxes21.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Odev2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end