classdef Odev3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SelectImageButton           matlab.ui.control.Button
        MorfolojiklemDropDownLabel  matlab.ui.control.Label
        MorfolojiklemDropDown       matlab.ui.control.DropDown
        OrginalImage                matlab.ui.control.UIAxes
        Histogram                   matlab.ui.control.UIAxes
        Threshold                   matlab.ui.control.UIAxes
        Morfoloji                   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function updateimage(app, imagefile)    
            global im;        
            global out;    
                try
                    im=imread(imagefile);
                     [r1,r2,r3]=size(im);
                if r3==3
                    im=rgb2gray(im);
                end
                    
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
             imshow(im,"Parent",app.OrginalImage);
              % Display the grayscale image
             imagesc(app.OrginalImage,im);
             histogram(app.Histogram,im,'FaceColor',[1,0.5,0],"EdgeColor","none");
        
             out= im>60;
             imshow(out,"Parent",app.Threshold);
            
           %  B=[0,1,0;1,1,1;0,1,0];
           %  C=padarray(im,[0 1],1);
           %   D=false(size(im));
           %  for i=1: size(C,1)-2
           %      for j=1: size(C,2)-2
           %          L=C(i:i+2,j:j+2);
           %          K=find(B==1);
           %          if(L(K)==1)
           %              D(i,j)=1;
           %          end
           %      end
           % end
           %  imshow(D,"Parent",app.Morfoloji);
            
             
          end  
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
             filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);  
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
       
               updateimage(app, fname);
            end
        end

        % Value changed function: MorfolojiklemDropDown
        function MorfolojiklemDropDownValueChanged(app, event)
        global im;
        global out;
        global value;
            value = app.MorfolojiklemDropDown.Value;
            if(value=="Erosion")
                se = strel('square',3); % 3x3'lük matris oluşturuyor bütün değerler 1
                 erodedBW = imerode(out,se);
                 imshow(erodedBW,"parent",app.Morfoloji);
            end
             if(value=="Dilation")
                 se = strel('square',3);
                 erodedBW = imdilate(out,se);
                 imshow(erodedBW,"parent",app.Morfoloji);
             end
              if(value=="Opening")
                 se = strel('square',3);
                 erodedBW = imopen(out,se);
                 imshow(erodedBW,"parent",app.Morfoloji);
              end
              if(value=="Closing")
                 se = strel('square',3);
                 erodedBW = imclose(out,se);
                 imshow(erodedBW,"parent",app.Morfoloji);
              end
              if(value=="Boundry extraction")
                 se = strel('square',3);
                 erodedBW = imerode(out,se);
                 imshow(out-erodedBW,"parent",app.Morfoloji);
              end
               if(value=="Skeleton")
                 Icomplement = imcomplement(round(out));
                 BW = imbinarize(Icomplement);
                 BW=bwskel(BW);
                 imshow(labeloverlay(round(out),BW,'Transparency',0),"parent",app.Morfoloji);
              end
               if(value=="Hole filling") 
                 BW=imfill(out,"holes");
                 imshow(BW,"parent",app.Morfoloji);
               end
               if(value=="Thickening") 
                 BW=bwmorph(out,"thicken");
                 imshow(BW,"parent",app.Morfoloji);
               end
                if(value=="Thining") 
                 BW=bwmorph(out,"thin");
                 imshow(BW,"parent",app.Morfoloji);
                end
                 if(value=="Hit andmiss")
                 %se1=out;
                 %se2=~out;
                 %BW=bwhitmiss(out,se1,se2);
                 %g=strel('square',3);
                 %BW=imdilate(BW,g);
                 interval=[0 1 0;1 1 1;0 1 0];
                 BW=bwhitmiss(out,interval);
                 imshow(BW,"parent",app.Morfoloji);
                 end
                 if(value=="Pruning") 
                 BW=bwmorph(out,"spur");
                 imshow(BW,"parent",app.Morfoloji);
                end
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.MorfolojiklemDropDownLabel = uilabel(app.UIFigure);
            app.MorfolojiklemDropDownLabel.Text = 'Morfolojik İşlem';
            app.MorfolojiklemDropDown = uidropdown(app.UIFigure);
            app.MorfolojiklemDropDown.Items = {'Erosion', 'Dilation', 'Opening', 'Closing', 'Thining', 'Thickening', 'Hit andmiss', 'Boundry extraction', 'Skeleton', 'Pruning', 'Hole filling'};
            app.MorfolojiklemDropDown.ValueChangedFcn = createCallbackFcn(app, @MorfolojiklemDropDownValueChanged, true);
            app.MorfolojiklemDropDown.Value = 'Erosion';
            app.OrginalImage = uiaxes(app.UIFigure);
            title(app.OrginalImage, 'Original Image')
            app.OrginalImage.XTick = [];
            app.OrginalImage.YTick = [];
            app.Histogram = uiaxes(app.UIFigure);
            title(app.Histogram, 'Histogram')
            xlabel(app.Histogram, 'x')
            ylabel(app.Histogram, 'y')
            app.Histogram.XTick = [0 128 255];
            app.Histogram.XTickLabel = {'0'; '128'; '255'};
            app.Histogram.YTick = [0 0.5 1];
            app.Histogram.YTickLabel = {'0'; '0.5'; '1'};
            app.Threshold = uiaxes(app.UIFigure);
            title(app.Threshold, 'Threshold')
            app.Threshold.XTick = [];
            app.Threshold.YTick = [];
            app.Morfoloji = uiaxes(app.UIFigure);
            title(app.Morfoloji, 'Morfolojik İşlem')
            app.Morfoloji.XTick = [];
            app.Morfoloji.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Odev3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end